classdef test_seriale < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        COMportDropDownLabel      matlab.ui.control.Label
        PortList                  matlab.ui.control.DropDown
        ConnectButton             matlab.ui.control.Button
        DisconnectButton          matlab.ui.control.Button
        ConnectionLampLabel       matlab.ui.control.Label
        Lamp                      matlab.ui.control.Lamp
        FixButton                 matlab.ui.control.Button
        TabGroup                  matlab.ui.container.TabGroup
        GearShiftTab              matlab.ui.container.Tab
        UITable_1                 matlab.ui.control.Table
        ReadButtonGearShift       matlab.ui.control.Button
        WriteButtonGearShift      matlab.ui.control.Button
        DELAYTextAreaLabel        matlab.ui.control.Label
        DELAYTextArea             matlab.ui.control.TextArea
        UP_REBOUNDTextAreaLabel   matlab.ui.control.Label
        UP_REBOUNDTextArea        matlab.ui.control.TextArea
        UP_BRAKETextAreaLabel     matlab.ui.control.Label
        UP_BRAKETextArea          matlab.ui.control.TextArea
        UP_PUSH_1_2TextAreaLabel  matlab.ui.control.Label
        UP_PUSH_1_2TextArea       matlab.ui.control.TextArea
        UP_PUSH_2_3TextAreaLabel  matlab.ui.control.Label
        UP_PUSH_2_3TextArea       matlab.ui.control.TextArea
        UP_PUSH_3_4TextAreaLabel  matlab.ui.control.Label
        UP_PUSH_3_4TextArea       matlab.ui.control.TextArea
        UP_PUSH_4_5TextAreaLabel  matlab.ui.control.Label
        UP_PUSH_4_5TextArea       matlab.ui.control.TextArea
        Page2Tab                  matlab.ui.container.Tab
        UITable_2                 matlab.ui.control.Table
        ReadButton_2              matlab.ui.control.Button
        WriteButton_2             matlab.ui.control.Button
        Page3Tab                  matlab.ui.container.Tab
        UITable_3                 matlab.ui.control.Table
        ReadButton_3              matlab.ui.control.Button
        WriteButton_3             matlab.ui.control.Button
        RxLabel                   matlab.ui.control.Label
        RxLamp                    matlab.ui.control.Lamp
        eepromAliveLabel          matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        testTxLabel               matlab.ui.control.Label
        TxLabel                   matlab.ui.control.Label
        TxLamp                    matlab.ui.control.Lamp
        testRxLabel               matlab.ui.control.Label
        ModLampLabel              matlab.ui.control.Label
        ModLamp                   matlab.ui.control.Lamp
    end

    properties (Access = private)
        s % serial port
%         values = zeros(1, 3); % knobs values (K1, K2, K3)
%         values_array; % array of color values in time
        UART_READ_EEPROM_DATA_WIDTH = 19+3;
        time % time array
        t % timer (for update GUI and send data)
        time_step = 0.1; % fixed time step of calling SendData function
        maxPages = 10;
        %lap color
        GREEN_COLOR = [0 1 0];
        RED_COLOR = [1 0 0];
        BLUE_COLOR = [0 0 1];
        CurrPage = 0;
        requestMsg = uint8(zeros(1,20));
        answerMsg = uint8(zeros(1,19));
        headerEeprom = "epr";
        headerMOD = "mod";
        temp = "000";
        readData = uint8(0);
        rxDataEeprom = uint8(zeros(1,22));
        
%         %editTable1 = @(r,c) app.UITable_1.Data(r,c);
%         %editTable2 = @(r,c) app.UITable_3.Data(r,c);
%         pageStruct = struct('page1', editTable1(r,c), 'page2', editTable2(r,c));
%         pageStruct.page1 = editTable1;
    end

    properties (Access = public)
        code = struct('readPageCom', "rp", 'readValueCom', "rv");

    end

    methods (Access = private)
    
        function SendData(app)
            if app.s.BytesToOutput == 0
                
            end
        end
        
        function ReadData(app)           
%             rxCom = fscanf(app.s, '%s', 3);
            %%app.RxLamp.Color = app.GREEN_COLOR;
%             if (~app.readData)
%                 app.temp(1) = app.temp(2);
%                 app.temp(2) = app.temp(3);
%                 app.temp(3) = app.rxData(1);
%             elseif(app.readData == 2)
%                 app.readData = 0;    
%             end
%             
%             if (strcmp(app.headerEeprom, app.temp))
%                 app.readData = 2;
%                 app.answerMsg = fread(app.s, 19);
%                 app.testRxLabel.Text = app.answerMsg;
%                 for i=1:3
%                     app.temp(i) = 0;
%                 end
%             else
%                 app.readData = fscanf(app.s, '%s', 1);
%             end
                
%                 for r = 1:16
%                     value = fread(app.s, 2);
%                     for c = 1:2
%                         app.UITable_1.Data(r,c) = value(c);
%                     end
%                 end
%             elseif (strcmp(rxCom, "rp2"))
%                 for r = 1:16
%                     value = fread(app.s, 2);
%                     for c = 1:2
%                         app.UITable_2.Data(r,c) = value(c);
%                     end
%                 end
%             elseif (strcmp(rxCom, "rp3"))
%                 for r = 1:16
%                     value = fread(app.s, 2);
%                     for c = 1:2
%                         app.UITable_3.Data(r,c) = value(c);
%                     end
%                 end
            
            %app.answerMsg = fread(app.s, 19);
% % %             switch app.answerMasg(1)
% % %                 case
% % %             end
%             app.temp(1) = fscanf(app.s, '%c', 1);
            app.temp(1) = fscanf(app.s, '%c', 1);
            if app.temp(1) == 'e'
                app.temp(2) = fscanf(app.s, '%c', 1);
                app.RxLamp.Color = app.BLUE_COLOR;
                if app.temp(2) == 'p'
                    app.temp(3) = fscanf(app.s, '%c', 1);
                    if app.temp(3) == 'r'
                        app.answerMsg = fscanf(app.s, '%s', 19);
                        app.testRxLabel.Text = app.answerMsg;
                        app.RxLamp.Color = app.BLUE_COLOR;
                    end
                end
            end
            app.temp(1) = char(0);
            app.temp(2) = char(0);
            app.temp(3) = char(0);
                
        end
    
    
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UITable_1.ColumnEditable = true;
            app.UITable_2.ColumnEditable = true;
            app.UITable_3.ColumnEditable = true;
            
            % set up timer
            app.t = timer;
            app.t.TimerFcn = @(~, ~)app.SendData;
            app.t.ExecutionMode = 'fixedRate';
            app.t.Period = app.time_step;
            app.t.BusyMode = 'drop';
            
            % looking for available serial ports 
            % (Instrument Control Toolbox required)
            p = instrhwinfo('serial');
            app.PortList.Items = p.AvailableSerialPorts;
            
            app.RxLamp.Color = app.RED_COLOR;
            app.TxLamp.Color = app.RED_COLOR;
            app.ModLamp.Color = app.RED_COLOR;
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            % on connect open serial port, start timer and change lamp color to green
            if strcmp(app.t.running, 'off')
                app.s = serial(app.PortList.Value);
                % call ReadData function if buffer contains uint16 (2 bytes)
                app.s.BytesAvailableFcnCount = 19+3;
                app.s.BytesAvailableFcnMode = 'byte';
                app.s.BytesAvailableFcn = @(~, ~)app.ReadData;
                app.s.BaudRate = 115200;
                fopen(app.s);
                start(app.t);
                app.Lamp.Color = app.GREEN_COLOR;
                fprintf(app.s, '%s', "mod");
                app.ModLamp.Color = app.GREEN_COLOR;
            end
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            % on disconnect stop timer, close serial port and change lamp color to red
            if strcmp(app.t.running, 'on')
                % mando comando stop modalità eeprom mod a gcu
                fprintf(app.s, '%s', "mod");
                app.ModLamp.Color = app.RED_COLOR;
                stop(app.t);
                stopasync(app.s)
                fclose(app.s);
                app.Lamp.Color = app.RED_COLOR;
                delete(instrfind);
            end
        end

        % Button pushed function: FixButton
        function FixButtonPushed(app, event)
            % delete all serial port objets from MATLAB memory
            % (Instrument Control Toolbox required)
            if strcmp(app.t.running, 'on')
                fprintf(app.s, '%s', "mod");
                app.ModLamp.Color = app.RED_COLOR;                
            end
            delete(instrfind);
            % looking for available serial ports 
            % (Instrument Control Toolbox required)
            p = instrhwinfo('serial');
            app.PortList.Items = p.AvailableSerialPorts;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % on disconnect stop timer, close serial port and change lamp color to red
            if strcmp(app.t.running, 'on')
                % mando comando stop modalità eeprom mod a gcu
                fprintf(app.s, '%s', "mod");
                app.ModLamp.Color = app.RED_COLOR;
                stop(app.t);
                stopasync(app.s)
                fclose(app.s);
                app.Lamp.Color = app.RED_COLOR;
                app.RxLamp.Color = app.RED_COLOR;
                delete(instrfind);
            end
            delete(app)
        end

        % Callback function
        function ReadMemoryButtonPushed(app, event)
% %             if app.s.BytesToOutput == 0
% % %                 switch app.CurrPage.Title
% % %                     case 1
% % %                         fprintf(app.s, '%s', "rp1");  
% % %                     case 2
% % %                         fprintf(app.s, '%s', "rp2");
% % %                     case 3
% % %                         fprintf(app.s, '%s', "rp3");
% % %                 end
% %                 if app.CurrPage.Title == "Page 1"
% %                     fprintf(app.s, '%s', "rp1");
% %                 end
% %                     
%             end
        end

        % Button pushed function: ReadButtonGearShift
        function ReadButtonGearShiftPushed(app, event)
            if app.s.BytesToOutput == 0
                app.requestMsg(1) = 'R';
                app.requestMsg(2) = '1';
                app.requestMsg(3) = '0';
                app.requestMsg(4:20) = '0';
                app.testTxLabel.Text = strcat("epr", char(app.requestMsg));
                fprintf(app.s, '%s', strcat("epr", char(app.requestMsg)));
                
                app.TxLamp.Color = app.BLUE_COLOR;
                %%inserire le ulteriori pagine per leggere tutti i parametri gearshift
            end
        end

        % Button pushed function: ReadButton_2
        function ReadButton_2Pushed(app, event)
            if app.s.BytesToOutput == 0
                fprintf(app.s, '%s', "rp2");
            end
        end

        % Button pushed function: ReadButton_3
        function ReadButton_3Pushed(app, event)
            if app.s.BytesToOutput == 0
                fprintf(app.s, '%s', "rp3");
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            switch selectedTab
                case app.GearShiftTab
                    app.EditField.Value = 1;
                    c = 1;
%                     app.UITable_1.Data(1,1) = "DELAY";
%                     app.UITable_1.Data(3,1) = "UP_REBOUND";
%                     app.UITable_1.Data(5,1) = "UP_BRAKE";
%                     app.UITable_1.Data(7,1) = "UP_PUSH_1_2";
%                     app.UITable_1.Data(7,1) = "UP_PUSH_2_3";
%                     app.UITable_1.Data(7,1) = "UP_PUSH_3_4";
%                     app.UITable_1.Data(7,1) = "UP_PUSH_4_5";   
                    app.DELAYTextArea.Value = string(0);
                    app.UP_REBOUNDTextArea.Value = string(1);
                    app.UP_BRAKETextArea.Value = string(2);
                    app.UP_PUSH_1_2TextArea.Value = string(3);
                    app.UP_PUSH_2_3TextArea.Value = string(4);
                    app.UP_PUSH_3_4TextArea.Value = string(5);
                    app.UP_PUSH_4_5TextArea.Value = string(6);
                case app.Page2Tab
                    app.EditField.Value = 2;
                    
            end
            
            app.RxLamp.Color = app.RED_COLOR;
        end

        % Button pushed function: WriteButtonGearShift, WriteButton_2
        function WriteButton_1Pushed(app, event)
            if app.s.BytesToOutput == 0
                for r = 1:16
                    for c = 1:2
                        value = app.UITable_1.Data(r,c);
                        fprintf(app.s, '%s', value);
                    end
                end
                
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.COMportDropDownLabel = uilabel(app.UIFigure);
            app.COMportDropDownLabel.Text = 'COM port';
            app.PortList = uidropdown(app.UIFigure);
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.UIFigure, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.ConnectionLampLabel = uilabel(app.UIFigure);
            app.ConnectionLampLabel.Text = 'Connection';
            app.Lamp = uilamp(app.UIFigure);
            app.FixButton = uibutton(app.UIFigure, 'push');
            app.FixButton.ButtonPushedFcn = createCallbackFcn(app, @FixButtonPushed, true);
            app.FixButton.Text = 'Fix';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.GearShiftTab = uitab(app.TabGroup);
            app.GearShiftTab.Title = 'GearShift';
            app.UITable_1 = uitable(app.GearShiftTab);
            app.UITable_1.ColumnName = {'Reference'; 'Value'};
            app.UITable_1.RowName = {};
            app.ReadButtonGearShift = uibutton(app.GearShiftTab, 'push');
            app.ReadButtonGearShift.ButtonPushedFcn = createCallbackFcn(app, @ReadButtonGearShiftPushed, true);
            app.ReadButtonGearShift.Text = 'Read';
            app.WriteButtonGearShift = uibutton(app.GearShiftTab, 'push');
            app.WriteButtonGearShift.ButtonPushedFcn = createCallbackFcn(app, @WriteButton_1Pushed, true);
            app.WriteButtonGearShift.Text = 'Write';
            app.DELAYTextAreaLabel = uilabel(app.GearShiftTab);
            app.DELAYTextAreaLabel.Text = 'DELAY';
            app.DELAYTextArea = uitextarea(app.GearShiftTab);
            app.UP_REBOUNDTextAreaLabel = uilabel(app.GearShiftTab);
            app.UP_REBOUNDTextAreaLabel.Text = 'UP_REBOUND';
            app.UP_REBOUNDTextArea = uitextarea(app.GearShiftTab);
            app.UP_BRAKETextAreaLabel = uilabel(app.GearShiftTab);
            app.UP_BRAKETextAreaLabel.Text = 'UP_BRAKE';
            app.UP_BRAKETextArea = uitextarea(app.GearShiftTab);
            app.UP_PUSH_1_2TextAreaLabel = uilabel(app.GearShiftTab);
            app.UP_PUSH_1_2TextAreaLabel.Text = 'UP_PUSH_1_2';
            app.UP_PUSH_1_2TextArea = uitextarea(app.GearShiftTab);
            app.UP_PUSH_2_3TextAreaLabel = uilabel(app.GearShiftTab);
            app.UP_PUSH_2_3TextAreaLabel.Text = 'UP_PUSH_2_3';
            app.UP_PUSH_2_3TextArea = uitextarea(app.GearShiftTab);
            app.UP_PUSH_3_4TextAreaLabel = uilabel(app.GearShiftTab);
            app.UP_PUSH_3_4TextAreaLabel.Text = 'UP_PUSH_3_4';
            app.UP_PUSH_3_4TextArea = uitextarea(app.GearShiftTab);
            app.UP_PUSH_4_5TextAreaLabel = uilabel(app.GearShiftTab);
            app.UP_PUSH_4_5TextAreaLabel.Text = 'UP_PUSH_4_5';
            app.UP_PUSH_4_5TextArea = uitextarea(app.GearShiftTab);
            app.Page2Tab = uitab(app.TabGroup);
            app.Page2Tab.Title = 'Page 2';
            app.UITable_2 = uitable(app.Page2Tab);
            app.UITable_2.ColumnName = {'Address'; 'Value'; 'Reference'};
            app.UITable_2.RowName = {};
            app.ReadButton_2 = uibutton(app.Page2Tab, 'push');
            app.ReadButton_2.ButtonPushedFcn = createCallbackFcn(app, @ReadButton_2Pushed, true);
            app.ReadButton_2.Text = 'Read';
            app.WriteButton_2 = uibutton(app.Page2Tab, 'push');
            app.WriteButton_2.ButtonPushedFcn = createCallbackFcn(app, @WriteButton_1Pushed, true);
            app.WriteButton_2.Text = 'Write';
            app.Page3Tab = uitab(app.TabGroup);
            app.Page3Tab.Title = 'Page 3';
            app.UITable_3 = uitable(app.Page3Tab);
            app.UITable_3.ColumnName = {'Address'; 'Value'; 'Reference'};
            app.UITable_3.RowName = {};
            app.ReadButton_3 = uibutton(app.Page3Tab, 'push');
            app.ReadButton_3.ButtonPushedFcn = createCallbackFcn(app, @ReadButton_3Pushed, true);
            app.ReadButton_3.Text = 'Read';
            app.WriteButton_3 = uibutton(app.Page3Tab, 'push');
            app.WriteButton_3.Text = 'Write';
            app.RxLabel = uilabel(app.UIFigure);
            app.RxLabel.Text = 'Rx';
            app.RxLamp = uilamp(app.UIFigure);
            app.eepromAliveLabel = uilabel(app.UIFigure);
            app.eepromAliveLabel.Text = '';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {''; ''};
            app.testTxLabel = uilabel(app.UIFigure);
            app.testTxLabel.Text = '';
            app.TxLabel = uilabel(app.UIFigure);
            app.TxLabel.Text = 'Tx';
            app.TxLamp = uilamp(app.UIFigure);
            app.testRxLabel = uilabel(app.UIFigure);
            app.testRxLabel.Text = '';
            app.ModLampLabel = uilabel(app.UIFigure);
            app.ModLampLabel.Text = 'Mod';
            app.ModLamp = uilamp(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = test_seriale

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end